classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ApplyButton               matlab.ui.control.Button
        ApplyStatus               matlab.ui.control.Label
        ResetSensorInformation    matlab.ui.control.Button
        SensorResetStatus         matlab.ui.control.Label
        TabGroupAnalogue          matlab.ui.container.TabGroup
        S1Tab                     matlab.ui.container.Tab
        S1UnitsOutputLabel        matlab.ui.control.Label
        S1UnitsALabel             matlab.ui.control.Label
        S1UnitsBLabel             matlab.ui.control.Label
        S1EnableSensor            matlab.ui.control.CheckBox
        OutputLabel               matlab.ui.control.Label
        S1EditFieldOutput         matlab.ui.control.NumericEditField
        aLabel                    matlab.ui.control.Label
        S1EditFieldA              matlab.ui.control.NumericEditField
        bLabel                    matlab.ui.control.Label
        S1EditFieldB              matlab.ui.control.NumericEditField
        S1TypeSelect              matlab.ui.container.ButtonGroup
        flowButton                matlab.ui.control.RadioButton
        pressureButton            matlab.ui.control.RadioButton
        S2Tab                     matlab.ui.container.Tab
        S2UnitsOutputLabel        matlab.ui.control.Label
        S2UnitsALabel             matlab.ui.control.Label
        S2UnitsBLabel             matlab.ui.control.Label
        S2EnableSensor            matlab.ui.control.CheckBox
        OutputLabel_3             matlab.ui.control.Label
        S2EditFieldOutput         matlab.ui.control.NumericEditField
        aLabel_2                  matlab.ui.control.Label
        S2EditFieldA              matlab.ui.control.NumericEditField
        bLabel_2                  matlab.ui.control.Label
        S2EditFieldB              matlab.ui.control.NumericEditField
        S2TypeSelect              matlab.ui.container.ButtonGroup
        flowButton_2              matlab.ui.control.RadioButton
        pressureButton_2          matlab.ui.control.RadioButton
        S3Tab                     matlab.ui.container.Tab
        S3UnitsOutputLabel        matlab.ui.control.Label
        S3UnitsALabel             matlab.ui.control.Label
        S3UnitsBLabel             matlab.ui.control.Label
        S3EnableSensor            matlab.ui.control.CheckBox
        OutputLabel_4             matlab.ui.control.Label
        S3EditFieldOutput         matlab.ui.control.NumericEditField
        aLabel_3                  matlab.ui.control.Label
        S3EditFieldA              matlab.ui.control.NumericEditField
        bLabel_3                  matlab.ui.control.Label
        S3EditFieldB              matlab.ui.control.NumericEditField
        S3TypeSelect              matlab.ui.container.ButtonGroup
        flowButton_3              matlab.ui.control.RadioButton
        pressureButton_3          matlab.ui.control.RadioButton
        S4Tab                     matlab.ui.container.Tab
        S4UnitsOutputLabel        matlab.ui.control.Label
        S4UnitsALabel             matlab.ui.control.Label
        S4UnitsBLabel             matlab.ui.control.Label
        S4EnableSensor            matlab.ui.control.CheckBox
        OutputLabel_5             matlab.ui.control.Label
        S4EditFieldOutput         matlab.ui.control.NumericEditField
        aLabel_4                  matlab.ui.control.Label
        S4EditFieldA              matlab.ui.control.NumericEditField
        bLabel_4                  matlab.ui.control.Label
        S4EditFieldB              matlab.ui.control.NumericEditField
        S4TypeSelect              matlab.ui.container.ButtonGroup
        flowButton_4              matlab.ui.control.RadioButton
        pressureButton_4          matlab.ui.control.RadioButton
        S5Tab                     matlab.ui.container.Tab
        S5UnitsOutputLabel        matlab.ui.control.Label
        S5UnitsALabel             matlab.ui.control.Label
        S5UnitsBLabel             matlab.ui.control.Label
        S5EnableSensor            matlab.ui.control.CheckBox
        OutputLabel_6             matlab.ui.control.Label
        S5EditFieldOutput         matlab.ui.control.NumericEditField
        aLabel_5                  matlab.ui.control.Label
        S5EditFieldA              matlab.ui.control.NumericEditField
        bLabel_5                  matlab.ui.control.Label
        S5EditFieldB              matlab.ui.control.NumericEditField
        S5TypeSelect              matlab.ui.container.ButtonGroup
        flowButton_5              matlab.ui.control.RadioButton
        pressureButton_5          matlab.ui.control.RadioButton
        SelectdeviceLabel         matlab.ui.control.Label
        SelectCOMPort             matlab.ui.control.DropDown
        RefreshCOMPorts           matlab.ui.control.Button
        StopButton                matlab.ui.control.Button
        flowGraph                 matlab.ui.control.UIAxes
        pressureGraph             matlab.ui.control.UIAxes
        TabGroupDigital           matlab.ui.container.TabGroup
        D1Tab                     matlab.ui.container.Tab
        D1UnitsOutputLabel        matlab.ui.control.Label
        D1EnableSensor            matlab.ui.control.CheckBox
        OutputLabel_7             matlab.ui.control.Label
        D1EditFieldOutput         matlab.ui.control.NumericEditField
        D1TypeSelect              matlab.ui.container.ButtonGroup
        flowButton_6              matlab.ui.control.RadioButton
        pressureButton_6          matlab.ui.control.RadioButton
        D2Tab                     matlab.ui.container.Tab
        D2UnitsOutputLabel        matlab.ui.control.Label
        D2EnableSensor            matlab.ui.control.CheckBox
        OutputLabel_12            matlab.ui.control.Label
        D2EditFieldOutput         matlab.ui.control.NumericEditField
        D2TypeSelect              matlab.ui.container.ButtonGroup
        flowButton_7              matlab.ui.control.RadioButton
        pressureButton_7          matlab.ui.control.RadioButton
        D3Tab                     matlab.ui.container.Tab
        D3UnitsOutputLabel        matlab.ui.control.Label
        D3EnableSensor            matlab.ui.control.CheckBox
        OutputLabel_13            matlab.ui.control.Label
        D3EditFieldOutput         matlab.ui.control.NumericEditField
        D3TypeSelect              matlab.ui.container.ButtonGroup
        flowButton_8              matlab.ui.control.RadioButton
        pressureButton_8          matlab.ui.control.RadioButton
        D4Tab                     matlab.ui.container.Tab
        D4UnitsOutputLabel        matlab.ui.control.Label
        D4EnableSensor            matlab.ui.control.CheckBox
        OutputLabel_14            matlab.ui.control.Label
        D4EditFieldOutput         matlab.ui.control.NumericEditField
        D4TypeSelect              matlab.ui.container.ButtonGroup
        flowButton_9              matlab.ui.control.RadioButton
        pressureButton_9          matlab.ui.control.RadioButton
        D5Tab                     matlab.ui.container.Tab
        D5UnitsOutputLabel        matlab.ui.control.Label
        D5EnableSensor            matlab.ui.control.CheckBox
        OutputLabel_15            matlab.ui.control.Label
        D5EditFieldOutput         matlab.ui.control.NumericEditField
        D5TypeSelect              matlab.ui.container.ButtonGroup
        flowButton_10             matlab.ui.control.RadioButton
        pressureButton_10         matlab.ui.control.RadioButton
        TabGroup                  matlab.ui.container.TabGroup
        P1Tab                     matlab.ui.container.Tab
        PumprateEditFieldLabel    matlab.ui.control.Label
        P1RateEditField           matlab.ui.control.NumericEditField
        P1RateUnitsLabel          matlab.ui.control.Label
        FeedbackLabel_4           matlab.ui.control.Label
        P1FeedbackSensor          matlab.ui.control.DropDown
        P2Tab                     matlab.ui.container.Tab
        P2RateUnitsLabel          matlab.ui.control.Label
        PumprateEditFieldLabel_2  matlab.ui.control.Label
        P2RateEditField           matlab.ui.control.NumericEditField
        FeedbackLabel_3           matlab.ui.control.Label
        P2FeedbackSensor          matlab.ui.control.DropDown
        P3Tab                     matlab.ui.container.Tab
        P3RateUnitsLabel          matlab.ui.control.Label
        PumprateEditFieldLabel_3  matlab.ui.control.Label
        P3RateEditField           matlab.ui.control.NumericEditField
        FeedbackLabel_2           matlab.ui.control.Label
        P3FeedbackSensor          matlab.ui.control.DropDown
        P4Tab                     matlab.ui.container.Tab
        P4RateUnitsLabel          matlab.ui.control.Label
        PumprateEditFieldLabel_4  matlab.ui.control.Label
        P4RateEditField           matlab.ui.control.NumericEditField
        FeedbackLabel             matlab.ui.control.Label
        P4FeedbackSensor          matlab.ui.control.DropDown
    end


    properties (Access = private)
        serialPortArduino % the serial port to which the arduino is connected.
        plotTimer % timer from which the plots will update.
        sensorData;
        pumpDropBoxItems % Items the drop box to select the feedback sensor.
    end

    methods (Access = private)
        function success = connectToArduino(app, comPort)
            success = true;
            if setSerialPort(comPort)
                if ~openSerialChannel(app)
                    success = false;
                    disp('Failed to open serial channel');
                end
            else
                success = false;
                disp('Failed to set serial port');
            end
        end % connectToArduino
        
        function disableSensorParameterInput(app, sensor)
            switch(sensor)
                case 'S1'
                    app.S1EditFieldOutput.Enable = 'off';
                    app.S1EditFieldB.Enable = 'off';
                    app.S1EditFieldA.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'S1');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'S1');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'S1');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'S1');
                case 'S2'
                    app.S2EditFieldOutput.Enable = 'off';
                    app.S2EditFieldB.Enable = 'off';
                    app.S2EditFieldA.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'S2');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'S2');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'S2');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'S2');
                case 'S3'
                    app.S3EditFieldOutput.Enable = 'off';
                    app.S3EditFieldB.Enable = 'off';
                    app.S3EditFieldA.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'S3');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'S3');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'S3');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'S3');
                case 'S4'
                    app.S4EditFieldOutput.Enable = 'off';
                    app.S4EditFieldB.Enable = 'off';
                    app.S4EditFieldA.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'S4');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'S4');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'S4');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'S4');
                case 'S5'
                    app.S5EditFieldOutput.Enable = 'off';
                    app.S5EditFieldB.Enable = 'off';
                    app.S5EditFieldA.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'S5');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'S5');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'S5');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'S5');
                case 'D1'
                    app.D1EditFieldOutput.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'D1');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'D1');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'D1');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'D1');
                case 'D2'
                    app.D2EditFieldOutput.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'D2');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'D2');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'D2');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'D2');
                case 'D3'
                    app.D3EditFieldOutput.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'D3');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'D3');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'D3');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'D3');
                case 'D4' 
                    app.D4EditFieldOutput.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'D4');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'D4');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'D4');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'D4');
                case 'D5'
                    app.D5EditFieldOutput.Enable = 'off';
                    removeItemFromPumpDropBox(app, app.P1FeedbackSensor, 'D5');
                    removeItemFromPumpDropBox(app, app.P2FeedbackSensor, 'D5');
                    removeItemFromPumpDropBox(app, app.P3FeedbackSensor, 'D5');
                    removeItemFromPumpDropBox(app, app.P4FeedbackSensor, 'D5');
            end
        end % disableSensorParameterInput
        
        function enableSensorParameterInput(app, sensor)
            switch(sensor)
                case 'S1'
                    app.S1EditFieldOutput.Enable = 'on';
                    app.S1EditFieldB.Enable = 'on';
                    app.S1EditFieldA.Enable = 'on';
                    if strcmp(app.S1TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'S1');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'S1');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'S1');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'S1');
                    end
                case 'S2'
                    app.S2EditFieldOutput.Enable = 'on';
                    app.S2EditFieldB.Enable = 'on';
                    app.S2EditFieldA.Enable = 'on';
                    if strcmp(app.S2TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'S2');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'S2');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'S2');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'S2');
                    end
                case 'S3'
                    app.S3EditFieldOutput.Enable = 'on';
                    app.S3EditFieldB.Enable = 'on';
                    app.S3EditFieldA.Enable = 'on';
                    if strcmp(app.S3TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'S3');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'S3');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'S3');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'S3');
                    end
                case 'S4'
                    app.S4EditFieldOutput.Enable = 'on';
                    app.S4EditFieldB.Enable = 'on';
                    app.S4EditFieldA.Enable = 'on';
                    if strcmp(app.S4TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'S4');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'S4');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'S4');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'S4');
                    end
                case 'S5'
                    app.S5EditFieldOutput.Enable = 'on';
                    app.S5EditFieldB.Enable = 'on';
                    app.S5EditFieldA.Enable = 'on';
                    if strcmp(app.S5TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'S5');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'S5');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'S5');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'S5');
                    end
                case 'D1'
                    app.D1EditFieldOutput.Enable = 'on';
                    if strcmp(app.D1TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'D1');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'D1');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'D1');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'D1');
                    end
                case 'D2'
                    app.D2EditFieldOutput.Enable = 'on';
                    if strcmp(app.D2TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'D2');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'D2');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'D2');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'D2');
                    end
                case 'D3'
                    app.D3EditFieldOutput.Enable = 'on';
                    if strcmp(app.D3TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'D3');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'D3');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'D3');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'D3');
                    end
                case 'D4'
                    app.D4EditFieldOutput.Enable = 'on';
                    if strcmp(app.D4TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'D4');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'D4');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'D4');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'D4');
                    end
                case 'D5'
                    app.D5EditFieldOutput.Enable = 'on';
                    if strcmp(app.D5TypeSelect.SelectedObject.Text, 'flow')
                        addItemToPumpDropBox(app, app.P1FeedbackSensor, 'D5');
                        addItemToPumpDropBox(app, app.P2FeedbackSensor, 'D5');
                        addItemToPumpDropBox(app, app.P3FeedbackSensor, 'D5');
                        addItemToPumpDropBox(app, app.P4FeedbackSensor, 'D5');
                    end
            end
        end % enableSensorParameterInput
        
        function success = setAnalogueSensorToWorkspace(app, enable, number, output, aVal, bVal, type)
            success = true;
            if enable
                 if ~setAnalogueSensor(number, output, aVal, bVal, type)
                     success = false; % only set success to false when a failure occurs.
                 end
            else
                if ~setAnalogueSensor(number, -1, 0, 0, '') % Set output to -1 to indicate disabled sensor.
                    success = false;
                end
            end
        end % setAnalogueSensorToWorkspace
        
        function success = setDigitalSensorToWorkspace(app, enable, number, output, type)
            success = true;
            if enable
                if ~setDigitalSensor(number, output, type)
                    success = false;
                end
            else
                if ~setDigitalSensor(number, -1, '')
                    success = false;
                end                
            end
        end % setDigitalSensorToWorkplace
        
        function success = setSensorParametersToMemory(app)
            success = true;
            if ~setAnalogueSensorToWorkspace(app, app.S1EnableSensor.Value, 1, app.S1EditFieldOutput.Value, ...
                    app.S1EditFieldA.Value, app.S1EditFieldB.Value, app.S1TypeSelect.SelectedObject.Text)
                success = false;
            end % S1
            
            if ~setAnalogueSensorToWorkspace(app, app.S2EnableSensor.Value, 2, app.S2EditFieldOutput.Value, ...
                    app.S2EditFieldA.Value, app.S2EditFieldB.Value, app.S2TypeSelect.SelectedObject.Text)
                success = false;
            end % S2
            
            if ~setAnalogueSensorToWorkspace(app, app.S3EnableSensor.Value, 3, app.S3EditFieldOutput.Value, ...
                    app.S3EditFieldA.Value, app.S3EditFieldB.Value, app.S3TypeSelect.SelectedObject.Text)
                success = false;
            end % S3
            
            if ~setAnalogueSensorToWorkspace(app, app.S4EnableSensor.Value, 4, app.S4EditFieldOutput.Value, ...
                    app.S4EditFieldA.Value, app.S4EditFieldB.Value, app.S4TypeSelect.SelectedObject.Text)
                success = false;
            end % S4
            
            if ~setAnalogueSensorToWorkspace(app, app.S5EnableSensor.Value, 5, app.S5EditFieldOutput.Value, ...
                    app.S5EditFieldA.Value, app.S5EditFieldB.Value, app.S5TypeSelect.SelectedObject.Text)
                success = false;
            end % S5
            
            if ~setDigitalSensorToWorkspace(app, app.D1EnableSensor.Value, 1,...
                    app.D1EditFieldOutput.Value, app.D1TypeSelect.SelectedObject.Text)
                success = false;
            end % D1
            
            if ~setDigitalSensorToWorkspace(app, app.D2EnableSensor.Value, 2,...
                    app.D2EditFieldOutput.Value, app.D2TypeSelect.SelectedObject.Text)
                success = false;
            end % D2
            
            if ~setDigitalSensorToWorkspace(app, app.D3EnableSensor.Value, 3,...
                    app.D3EditFieldOutput.Value, app.D3TypeSelect.SelectedObject.Text)
                success = false;
            end % D3
            
            if ~setDigitalSensorToWorkspace(app, app.D4EnableSensor.Value, 4,...
                    app.D4EditFieldOutput.Value, app.D4TypeSelect.SelectedObject.Text)
                success = false;
            end % D4
            
            if ~setDigitalSensorToWorkspace(app, app.D5EnableSensor.Value, 5,...
                    app.D5EditFieldOutput.Value, app.D5TypeSelect.SelectedObject.Text)
                success = false;
            end % D5
            
        end % setSensorParametersToMemory
        
        function success = setPumpParametersToMemory(app)
            success = true;
            if ~setPumpToWorkspace(app, 1, app.P1RateEditField.Value, app.P1FeedbackSensor.Value)
                success = false;
            end
            
            if ~setPumpToWorkspace(app, 2, app.P2RateEditField.Value, app.P2FeedbackSensor.Value)
                success = false;
            end
            
            if ~setPumpToWorkspace(app, 3, app.P3RateEditField.Value, app.P3FeedbackSensor.Value)
                success = false;
            end
            
            if ~setPumpToWorkspace(app, 4, app.P4RateEditField.Value, app.P4FeedbackSensor.Value)
                success = false;
            end
        end % setPumpParametersToMemory

        function success = setPumpToWorkspace(app, pumpNumber, pumpVal, feedbackSensor)
            success = true;
            if pumpVal == 0
                if ~setPump(pumpNumber, pumpVal, '')
                    success = false;
                end
            else
                if ~setPump(pumpNumber, pumpVal, feedbackSensor)
                    success = false;
                end
            end
        end % setPumpToWorkspace

        function prepareAndSetupForSerialCommunication(app)
            evalin('base', 'sensorData = handleForData;');
            app.sensorData = evalin('base', 'sensorData');
            
            tempString = strcat('outFile = fopen(''../Output/', datestr(now, 'yyyy-mm-dd_HHMMSS'), '_sensorData.csv'', ''w'');');
            evalin('base', tempString);
            cla(app.flowGraph);
            cla(app.pressureGraph);
            plot(app.flowGraph, (1:10)', zeros(10,5)); % empty graph to initialise.
            plot(app.pressureGraph, (1:10)', zeros(10,5));
            
            if connectToArduino(app, app.SelectCOMPort.Value)
                if strcmp(app.plotTimer.Running, 'off')
                    start(app.plotTimer);
                elseif strcmp(app.plotTimer.Running, 'on')
                    stop(app.plotTimer);
                    start(app.plotTimer);
                else
                    fprintf(2, 'Timer problem.\r\n');
                end
            end
        end % prepareAndSetupForSerialCommunication
        
        function addItemToPumpDropBox(app, pumpDropdown, sensor)
            tempCell = pumpDropdown.Items;
            if isempty(find(strcmp(tempCell, sensor))) % only add when it is not added yet.
                tempCell{size(tempCell,2)+1} = sensor;
                pumpDropdown.Items = tempCell;
            end
        end % addITemToPumpDropBox
        
        function removeItemFromPumpDropBox(app, pumpDropdown, sensor)
            tempCell = pumpDropdown.Items;
            index = find(strcmp(tempCell, sensor));
            if ~isempty(index)
                tempCell{index}=[];
                goodVals = ~cellfun('isempty', tempCell);
                tempCell = tempCell(goodVals);
            end
            pumpDropdown.Items = tempCell;
        end % removeItemFromPumpDropBox

        function addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor)
            if strcmp(selectedButton, 'flow')
                addItemToPumpDropBox(app, app.P1FeedbackSensor, sensor);
                addItemToPumpDropBox(app, app.P2FeedbackSensor, sensor);
                addItemToPumpDropBox(app, app.P3FeedbackSensor, sensor);
                addItemToPumpDropBox(app, app.P4FeedbackSensor, sensor);
            elseif strcmp(selectedButton, 'pressure')
                removeItemFromPumpDropBox(app, app.P1FeedbackSensor, sensor);
                removeItemFromPumpDropBox(app, app.P2FeedbackSensor, sensor);
                removeItemFromPumpDropBox(app, app.P3FeedbackSensor, sensor);
                removeItemFromPumpDropBox(app, app.P4FeedbackSensor, sensor);
            else
                fprintf(2, '%s type select unrecognised.', sensor);
            end
        end
        
    end % functions


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.ApplyStatus.Visible = 'off';
            app.SensorResetStatus.Visible = 'off';
            assignin('base', 'app', app);
            
            disp('Setting up timer');
            %% Start timer when arduino is successfully connected.
            app.plotTimer = timer('Period', 0.1, ... % Timer that fires every 100ms.
                'ExecutionMode', 'fixedRate',... % always fire every 100ms, timer resets immediately after function call.
                'TasksToExecute', inf); 
            app.plotTimer.TimerFcn = {@livePlot, app}; 
            app.plotTimer.StartFcn = @(~,~)disp('Starting timer.');
            app.plotTimer.StopFcn = @(~,~)disp('Stopping timer.');
            clear functions;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            success = true;
            if ~setSensorParametersToMemory(app)
                success = false;
            end
            
            if ~setPumpParametersToMemory(app)
                success = false;
            end
            
            if success % only start the communication when everything if okay.
                prepareAndSetupForSerialCommunication(app);
            end
            
            app.ApplyStatus.Visible = 'on';
            if success
                app.ApplyStatus.Text = 'Success!';
            else
                app.ApplyStatus.Text = 'Failed!';
            end
            
            
        end

        % Value changed function: S1EnableSensor
        function S1EnableSensorValueChanged(app, event)
            if app.S1EnableSensor.Value
                enableSensorParameterInput(app, 'S1');
            else
                disableSensorParameterInput(app, 'S1');
            end
        end

        % Value changed function: S2EnableSensor
        function S2EnableSensorValueChanged(app, event)
            if app.S2EnableSensor.Value
                enableSensorParameterInput(app, 'S2');
            else
                disableSensorParameterInput(app, 'S2');
            end
        end

        % Button pushed function: ResetSensorInformation
        function ResetSensorInformationButtonPushed(app, event)
            app.SensorResetStatus.Visible = 'on';
            if evalin('base', 'exist(''Sensors'')')
                evalin('base', 'clear setSensor'); % Delete the previous sensor information
                evalin('base', 'clear Sensors'); % Delete the previous sensor information
                if evalin('base', 'exist(''Sensors'')')
                    app.SensorResetStatus.Text = 'Failed!';
                    fprintf(2, 'Failed to remove old instance of sensor information.\r\n');
                else
                    app.SensorResetStatus.Text = 'Success!';
                end
            else
                app.SensorResetStatus.Text = 'Success!';
            end
        end

        % Button pushed function: RefreshCOMPorts
        function RefreshCOMPortsButtonPushed(app, event)
            serialDevices = seriallist;
            numberOfDevices = size(serialDevices,2);
            if numberOfDevices < 1
                app.SelectCOMPort.Items = {'No devices'};
            else
                for i = 1:numberOfDevices
                   tempCell{i} = convertStringsToChars(serialDevices(i));
                end
                app.SelectCOMPort.Items = tempCell;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            status = evalin('base', 'exist(''arduinoSerial'')');
            if status % Close if the channel has been created.
                if evalin('base', 'isvalid(arduinoSerial)')
                    evalin('base', 'fclose(arduinoSerial)'); % Close the channel that communicates with Arduino, can still open afterwards.
                    evalin('base', 'delete(arduinoSerial)'); % Releases the channel such that the COM port can be used again.
                end
                evalin('base', 'clear(''arduinoSerial'')'); % Removes the variable from the workspace since it cannot be opened again after delete.
            end
            
            status = evalin('base', 'exist(''outFile'');');
            if status
                % outFile exists and is opened.
                evalin('base', 'fclose(outFile);');
                evalin('base', 'clear(''outFile'');');
                status = evalin('base', 'exist(''sensorData'');');
                if status
                    evalin('base', 'clear(''sensorData'');');
                end
            end
            
            if strcmp(app.plotTimer.Running, 'on')
                stop(app.plotTimer);
            end
            clear functions; % clear persistent data in functions.
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            StopButtonPushed(app, event);
            delete(app)
        end

        % Value changed function: S3EnableSensor
        function S3EnableSensorValueChanged(app, event)
            if app.S3EnableSensor.Value
                enableSensorParameterInput(app, 'S3');
            else
                disableSensorParameterInput(app, 'S3');
            end
        end

        % Value changed function: S4EnableSensor
        function S4EnableSensorValueChanged(app, event)
            if app.S4EnableSensor.Value
                enableSensorParameterInput(app, 'S4');
            else
                disableSensorParameterInput(app, 'S4');
            end
        end

        % Value changed function: S5EnableSensor
        function S5EnableSensorValueChanged(app, event)
            if app.S5EnableSensor.Value
                enableSensorParameterInput(app, 'S5');
            else
                disableSensorParameterInput(app, 'S5');
            end
        end

        % Value changed function: D1EnableSensor
        function D1EnableSensorValueChanged(app, event)
            if app.D1EnableSensor.Value
                enableSensorParameterInput(app, 'D1');
            else
                disableSensorParameterInput(app, 'D1');
            end
        end

        % Value changed function: D2EnableSensor
        function D2EnableSensorValueChanged(app, event)
            if app.D2EnableSensor.Value
                enableSensorParameterInput(app, 'D2');
            else
                disableSensorParameterInput(app, 'D2');
            end
        end

        % Value changed function: D3EnableSensor
        function D3EnableSensorValueChanged(app, event)
            if app.D3EnableSensor.Value
                enableSensorParameterInput(app, 'D3');
            else
                disableSensorParameterInput(app, 'D3');
            end
        end

        % Value changed function: D4EnableSensor
        function D4EnableSensorValueChanged(app, event)
            if app.D4EnableSensor.Value
                enableSensorParameterInput(app, 'D4');
            else
                disableSensorParameterInput(app, 'D4');
            end
        end

        % Value changed function: D5EnableSensor
        function D5EnableSensorValueChanged(app, event)
            if app.D5EnableSensor.Value
                enableSensorParameterInput(app, 'D5');
            else
                disableSensorParameterInput(app, 'D5');
            end
        end

        % Selection changed function: S1TypeSelect
        function S1TypeSelectSelectionChanged(app, event)
            selectedButton = app.S1TypeSelect.SelectedObject.Text;
            sensor = 'S1';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
        end

        % Selection changed function: S2TypeSelect
        function S2TypeSelectSelectionChanged(app, event)
            selectedButton = app.S2TypeSelect.SelectedObject.Text;
            sensor = 'S2';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
        end

        % Selection changed function: S3TypeSelect
        function S3TypeSelectSelectionChanged(app, event)
            selectedButton = app.S3TypeSelect.SelectedObject.Text;
            sensor = 'S3';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
        end

        % Selection changed function: S4TypeSelect
        function S4TypeSelectSelectionChanged(app, event)
            selectedButton = app.S4TypeSelect.SelectedObject.Text;
            sensor = 'S4';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
        end

        % Selection changed function: S5TypeSelect
        function S5TypeSelectSelectionChanged(app, event)
            selectedButton = app.S5TypeSelect.SelectedObject.Text;
            sensor = 'S5';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
        end

        % Selection changed function: D1TypeSelect
        function D1TypeSelectSelectionChanged(app, event)
            selectedButton = app.D1TypeSelect.SelectedObject.Text;
            sensor = 'D1';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
        end

        % Selection changed function: D2TypeSelect
        function D2TypeSelectSelectionChanged(app, event)
            selectedButton = app.D2TypeSelect.SelectedObject.Text;
            sensor = 'D2';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
        end

        % Selection changed function: D3TypeSelect
        function D3TypeSelectSelectionChanged(app, event)
            selectedButton = app.D3TypeSelect.SelectedObject.Text;
            sensor = 'D3';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
        end

        % Selection changed function: D4TypeSelect
        function D4TypeSelectSelectionChanged(app, event)
            selectedButton = app.D4TypeSelect.SelectedObject.Text;
            sensor = 'D4';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
        end

        % Selection changed function: D5TypeSelect
        function D5TypeSelectSelectionChanged(app, event)
            selectedButton = app.D5TypeSelect.SelectedObject.Text;
            sensor = 'D5';
            addOrRemoveItemToPumpDropBoxes(app, selectedButton, sensor);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ApplyStatus = uilabel(app.UIFigure);
            app.ApplyStatus.Text = 'Success!';
            app.ResetSensorInformation = uibutton(app.UIFigure, 'push');
            app.ResetSensorInformation.ButtonPushedFcn = createCallbackFcn(app, @ResetSensorInformationButtonPushed, true);
            app.ResetSensorInformation.Text = 'Reset sensors';
            app.SensorResetStatus = uilabel(app.UIFigure);
            app.SensorResetStatus.Text = 'Success!';
            app.TabGroupAnalogue = uitabgroup(app.UIFigure);
            app.S1Tab = uitab(app.TabGroupAnalogue);
            app.S1Tab.Title = 'S1';
            app.S1UnitsOutputLabel = uilabel(app.S1Tab);
            app.S1UnitsOutputLabel.Text = 'Volt';
            app.S1UnitsALabel = uilabel(app.S1Tab);
            app.S1UnitsALabel.Text = 'V/unit';
            app.S1UnitsBLabel = uilabel(app.S1Tab);
            app.S1UnitsBLabel.Text = 'Volt';
            app.S1EnableSensor = uicheckbox(app.S1Tab);
            app.S1EnableSensor.ValueChangedFcn = createCallbackFcn(app, @S1EnableSensorValueChanged, true);
            app.S1EnableSensor.Text = 'Enable';
            app.OutputLabel = uilabel(app.S1Tab);
            app.OutputLabel.Enable = 'off';
            app.OutputLabel.Text = 'Output:';
            app.S1EditFieldOutput = uieditfield(app.S1Tab, 'numeric');
            app.S1EditFieldOutput.Limits = [0 Inf];
            app.S1EditFieldOutput.Enable = 'off';
            app.aLabel = uilabel(app.S1Tab);
            app.aLabel.Enable = 'off';
            app.aLabel.Text = 'a:';
            app.S1EditFieldA = uieditfield(app.S1Tab, 'numeric');
            app.S1EditFieldA.Limits = [0 Inf];
            app.S1EditFieldA.Enable = 'off';
            app.bLabel = uilabel(app.S1Tab);
            app.bLabel.Enable = 'off';
            app.bLabel.Text = 'b:';
            app.S1EditFieldB = uieditfield(app.S1Tab, 'numeric');
            app.S1EditFieldB.Limits = [0 Inf];
            app.S1EditFieldB.Enable = 'off';
            app.S1TypeSelect = uibuttongroup(app.S1Tab);
            app.S1TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @S1TypeSelectSelectionChanged, true);
            app.flowButton = uiradiobutton(app.S1TypeSelect);
            app.flowButton.Text = 'flow';
            app.flowButton.Value = true;
            app.pressureButton = uiradiobutton(app.S1TypeSelect);
            app.pressureButton.Text = 'pressure';
            app.S2Tab = uitab(app.TabGroupAnalogue);
            app.S2Tab.Title = 'S2';
            app.S2UnitsOutputLabel = uilabel(app.S2Tab);
            app.S2UnitsOutputLabel.Text = 'Volt';
            app.S2UnitsALabel = uilabel(app.S2Tab);
            app.S2UnitsALabel.Text = 'V/unit';
            app.S2UnitsBLabel = uilabel(app.S2Tab);
            app.S2UnitsBLabel.Text = 'Volt';
            app.S2EnableSensor = uicheckbox(app.S2Tab);
            app.S2EnableSensor.ValueChangedFcn = createCallbackFcn(app, @S2EnableSensorValueChanged, true);
            app.S2EnableSensor.Text = 'Enable';
            app.OutputLabel_3 = uilabel(app.S2Tab);
            app.OutputLabel_3.Enable = 'off';
            app.OutputLabel_3.Text = 'Output:';
            app.S2EditFieldOutput = uieditfield(app.S2Tab, 'numeric');
            app.S2EditFieldOutput.Limits = [0 Inf];
            app.S2EditFieldOutput.Enable = 'off';
            app.aLabel_2 = uilabel(app.S2Tab);
            app.aLabel_2.Enable = 'off';
            app.aLabel_2.Text = 'a:';
            app.S2EditFieldA = uieditfield(app.S2Tab, 'numeric');
            app.S2EditFieldA.Limits = [0 Inf];
            app.S2EditFieldA.Enable = 'off';
            app.bLabel_2 = uilabel(app.S2Tab);
            app.bLabel_2.Enable = 'off';
            app.bLabel_2.Text = 'b:';
            app.S2EditFieldB = uieditfield(app.S2Tab, 'numeric');
            app.S2EditFieldB.Limits = [0 Inf];
            app.S2EditFieldB.Enable = 'off';
            app.S2TypeSelect = uibuttongroup(app.S2Tab);
            app.S2TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @S2TypeSelectSelectionChanged, true);
            app.flowButton_2 = uiradiobutton(app.S2TypeSelect);
            app.flowButton_2.Text = 'flow';
            app.flowButton_2.Value = true;
            app.pressureButton_2 = uiradiobutton(app.S2TypeSelect);
            app.pressureButton_2.Text = 'pressure';
            app.S3Tab = uitab(app.TabGroupAnalogue);
            app.S3Tab.Title = 'S3';
            app.S3UnitsOutputLabel = uilabel(app.S3Tab);
            app.S3UnitsOutputLabel.Text = 'Volt';
            app.S3UnitsALabel = uilabel(app.S3Tab);
            app.S3UnitsALabel.Text = 'V/unit';
            app.S3UnitsBLabel = uilabel(app.S3Tab);
            app.S3UnitsBLabel.Text = 'Volt';
            app.S3EnableSensor = uicheckbox(app.S3Tab);
            app.S3EnableSensor.ValueChangedFcn = createCallbackFcn(app, @S3EnableSensorValueChanged, true);
            app.S3EnableSensor.Text = 'Enable';
            app.OutputLabel_4 = uilabel(app.S3Tab);
            app.OutputLabel_4.Enable = 'off';
            app.OutputLabel_4.Text = 'Output:';
            app.S3EditFieldOutput = uieditfield(app.S3Tab, 'numeric');
            app.S3EditFieldOutput.Limits = [0 Inf];
            app.S3EditFieldOutput.Enable = 'off';
            app.aLabel_3 = uilabel(app.S3Tab);
            app.aLabel_3.Enable = 'off';
            app.aLabel_3.Text = 'a:';
            app.S3EditFieldA = uieditfield(app.S3Tab, 'numeric');
            app.S3EditFieldA.Limits = [0 Inf];
            app.S3EditFieldA.Enable = 'off';
            app.bLabel_3 = uilabel(app.S3Tab);
            app.bLabel_3.Enable = 'off';
            app.bLabel_3.Text = 'b:';
            app.S3EditFieldB = uieditfield(app.S3Tab, 'numeric');
            app.S3EditFieldB.Limits = [0 Inf];
            app.S3EditFieldB.Enable = 'off';
            app.S3TypeSelect = uibuttongroup(app.S3Tab);
            app.S3TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @S3TypeSelectSelectionChanged, true);
            app.flowButton_3 = uiradiobutton(app.S3TypeSelect);
            app.flowButton_3.Text = 'flow';
            app.flowButton_3.Value = true;
            app.pressureButton_3 = uiradiobutton(app.S3TypeSelect);
            app.pressureButton_3.Text = 'pressure';
            app.S4Tab = uitab(app.TabGroupAnalogue);
            app.S4Tab.Title = 'S4';
            app.S4UnitsOutputLabel = uilabel(app.S4Tab);
            app.S4UnitsOutputLabel.Text = 'Volt';
            app.S4UnitsALabel = uilabel(app.S4Tab);
            app.S4UnitsALabel.Text = 'V/unit';
            app.S4UnitsBLabel = uilabel(app.S4Tab);
            app.S4UnitsBLabel.Text = 'Volt';
            app.S4EnableSensor = uicheckbox(app.S4Tab);
            app.S4EnableSensor.ValueChangedFcn = createCallbackFcn(app, @S4EnableSensorValueChanged, true);
            app.S4EnableSensor.Text = 'Enable';
            app.OutputLabel_5 = uilabel(app.S4Tab);
            app.OutputLabel_5.Enable = 'off';
            app.OutputLabel_5.Text = 'Output:';
            app.S4EditFieldOutput = uieditfield(app.S4Tab, 'numeric');
            app.S4EditFieldOutput.Limits = [0 Inf];
            app.S4EditFieldOutput.Enable = 'off';
            app.aLabel_4 = uilabel(app.S4Tab);
            app.aLabel_4.Enable = 'off';
            app.aLabel_4.Text = 'a:';
            app.S4EditFieldA = uieditfield(app.S4Tab, 'numeric');
            app.S4EditFieldA.Limits = [0 Inf];
            app.S4EditFieldA.Enable = 'off';
            app.bLabel_4 = uilabel(app.S4Tab);
            app.bLabel_4.Enable = 'off';
            app.bLabel_4.Text = 'b:';
            app.S4EditFieldB = uieditfield(app.S4Tab, 'numeric');
            app.S4EditFieldB.Limits = [0 Inf];
            app.S4EditFieldB.Enable = 'off';
            app.S4TypeSelect = uibuttongroup(app.S4Tab);
            app.S4TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @S4TypeSelectSelectionChanged, true);
            app.flowButton_4 = uiradiobutton(app.S4TypeSelect);
            app.flowButton_4.Text = 'flow';
            app.flowButton_4.Value = true;
            app.pressureButton_4 = uiradiobutton(app.S4TypeSelect);
            app.pressureButton_4.Text = 'pressure';
            app.S5Tab = uitab(app.TabGroupAnalogue);
            app.S5Tab.Title = 'S5';
            app.S5UnitsOutputLabel = uilabel(app.S5Tab);
            app.S5UnitsOutputLabel.Text = 'Volt';
            app.S5UnitsALabel = uilabel(app.S5Tab);
            app.S5UnitsALabel.Text = 'V/unit';
            app.S5UnitsBLabel = uilabel(app.S5Tab);
            app.S5UnitsBLabel.Text = 'Volt';
            app.S5EnableSensor = uicheckbox(app.S5Tab);
            app.S5EnableSensor.ValueChangedFcn = createCallbackFcn(app, @S5EnableSensorValueChanged, true);
            app.S5EnableSensor.Text = 'Enable';
            app.OutputLabel_6 = uilabel(app.S5Tab);
            app.OutputLabel_6.Enable = 'off';
            app.OutputLabel_6.Text = 'Output:';
            app.S5EditFieldOutput = uieditfield(app.S5Tab, 'numeric');
            app.S5EditFieldOutput.Limits = [0 Inf];
            app.S5EditFieldOutput.Enable = 'off';
            app.aLabel_5 = uilabel(app.S5Tab);
            app.aLabel_5.Enable = 'off';
            app.aLabel_5.Text = 'a:';
            app.S5EditFieldA = uieditfield(app.S5Tab, 'numeric');
            app.S5EditFieldA.Limits = [0 Inf];
            app.S5EditFieldA.Enable = 'off';
            app.bLabel_5 = uilabel(app.S5Tab);
            app.bLabel_5.Enable = 'off';
            app.bLabel_5.Text = 'b:';
            app.S5EditFieldB = uieditfield(app.S5Tab, 'numeric');
            app.S5EditFieldB.Limits = [0 Inf];
            app.S5EditFieldB.Enable = 'off';
            app.S5TypeSelect = uibuttongroup(app.S5Tab);
            app.S5TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @S5TypeSelectSelectionChanged, true);
            app.flowButton_5 = uiradiobutton(app.S5TypeSelect);
            app.flowButton_5.Text = 'flow';
            app.flowButton_5.Value = true;
            app.pressureButton_5 = uiradiobutton(app.S5TypeSelect);
            app.pressureButton_5.Text = 'pressure';
            app.SelectdeviceLabel = uilabel(app.UIFigure);
            app.SelectdeviceLabel.Text = 'Select device:';
            app.SelectCOMPort = uidropdown(app.UIFigure);
            app.SelectCOMPort.Items = {'No devices'};
            app.SelectCOMPort.Value = 'No devices';
            app.RefreshCOMPorts = uibutton(app.UIFigure, 'push');
            app.RefreshCOMPorts.ButtonPushedFcn = createCallbackFcn(app, @RefreshCOMPortsButtonPushed, true);
            app.RefreshCOMPorts.Text = 'Refresh';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.flowGraph = uiaxes(app.UIFigure);
            title(app.flowGraph, 'Flow')
            xlabel(app.flowGraph, 't')
            ylabel(app.flowGraph, 'L/min')
            app.pressureGraph = uiaxes(app.UIFigure);
            title(app.pressureGraph, 'Pressure')
            xlabel(app.pressureGraph, 't')
            ylabel(app.pressureGraph, 'mmHg')
            app.TabGroupDigital = uitabgroup(app.UIFigure);
            app.D1Tab = uitab(app.TabGroupDigital);
            app.D1Tab.Title = 'D1';
            app.D1UnitsOutputLabel = uilabel(app.D1Tab);
            app.D1UnitsOutputLabel.Text = 'P/unit';
            app.D1EnableSensor = uicheckbox(app.D1Tab);
            app.D1EnableSensor.ValueChangedFcn = createCallbackFcn(app, @D1EnableSensorValueChanged, true);
            app.D1EnableSensor.Text = 'Enable';
            app.OutputLabel_7 = uilabel(app.D1Tab);
            app.OutputLabel_7.Enable = 'off';
            app.OutputLabel_7.Text = 'Output:';
            app.D1EditFieldOutput = uieditfield(app.D1Tab, 'numeric');
            app.D1EditFieldOutput.Limits = [0 Inf];
            app.D1EditFieldOutput.Enable = 'off';
            app.D1TypeSelect = uibuttongroup(app.D1Tab);
            app.D1TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @D1TypeSelectSelectionChanged, true);
            app.flowButton_6 = uiradiobutton(app.D1TypeSelect);
            app.flowButton_6.Text = 'flow';
            app.flowButton_6.Value = true;
            app.pressureButton_6 = uiradiobutton(app.D1TypeSelect);
            app.pressureButton_6.Text = 'pressure';
            app.D2Tab = uitab(app.TabGroupDigital);
            app.D2Tab.Title = 'D2';
            app.D2UnitsOutputLabel = uilabel(app.D2Tab);
            app.D2UnitsOutputLabel.Text = 'P/unit';
            app.D2EnableSensor = uicheckbox(app.D2Tab);
            app.D2EnableSensor.ValueChangedFcn = createCallbackFcn(app, @D2EnableSensorValueChanged, true);
            app.D2EnableSensor.Text = 'Enable';
            app.OutputLabel_12 = uilabel(app.D2Tab);
            app.OutputLabel_12.Enable = 'off';
            app.OutputLabel_12.Text = 'Output:';
            app.D2EditFieldOutput = uieditfield(app.D2Tab, 'numeric');
            app.D2EditFieldOutput.Limits = [0 Inf];
            app.D2EditFieldOutput.Enable = 'off';
            app.D2TypeSelect = uibuttongroup(app.D2Tab);
            app.D2TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @D2TypeSelectSelectionChanged, true);
            app.flowButton_7 = uiradiobutton(app.D2TypeSelect);
            app.flowButton_7.Text = 'flow';
            app.flowButton_7.Value = true;
            app.pressureButton_7 = uiradiobutton(app.D2TypeSelect);
            app.pressureButton_7.Text = 'pressure';
            app.D3Tab = uitab(app.TabGroupDigital);
            app.D3Tab.Title = 'D3';
            app.D3UnitsOutputLabel = uilabel(app.D3Tab);
            app.D3UnitsOutputLabel.Text = 'P/unit';
            app.D3EnableSensor = uicheckbox(app.D3Tab);
            app.D3EnableSensor.ValueChangedFcn = createCallbackFcn(app, @D3EnableSensorValueChanged, true);
            app.D3EnableSensor.Text = 'Enable';
            app.OutputLabel_13 = uilabel(app.D3Tab);
            app.OutputLabel_13.Enable = 'off';
            app.OutputLabel_13.Text = 'Output:';
            app.D3EditFieldOutput = uieditfield(app.D3Tab, 'numeric');
            app.D3EditFieldOutput.Limits = [0 Inf];
            app.D3EditFieldOutput.Enable = 'off';
            app.D3TypeSelect = uibuttongroup(app.D3Tab);
            app.D3TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @D3TypeSelectSelectionChanged, true);
            app.flowButton_8 = uiradiobutton(app.D3TypeSelect);
            app.flowButton_8.Text = 'flow';
            app.flowButton_8.Value = true;
            app.pressureButton_8 = uiradiobutton(app.D3TypeSelect);
            app.pressureButton_8.Text = 'pressure';
            app.D4Tab = uitab(app.TabGroupDigital);
            app.D4Tab.Title = 'D4';
            app.D4UnitsOutputLabel = uilabel(app.D4Tab);
            app.D4UnitsOutputLabel.Text = 'P/unit';
            app.D4EnableSensor = uicheckbox(app.D4Tab);
            app.D4EnableSensor.ValueChangedFcn = createCallbackFcn(app, @D4EnableSensorValueChanged, true);
            app.D4EnableSensor.Text = 'Enable';
            app.OutputLabel_14 = uilabel(app.D4Tab);
            app.OutputLabel_14.Enable = 'off';
            app.OutputLabel_14.Text = 'Output:';
            app.D4EditFieldOutput = uieditfield(app.D4Tab, 'numeric');
            app.D4EditFieldOutput.Limits = [0 Inf];
            app.D4EditFieldOutput.Enable = 'off';
            app.D4TypeSelect = uibuttongroup(app.D4Tab);
            app.D4TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @D4TypeSelectSelectionChanged, true);
            app.flowButton_9 = uiradiobutton(app.D4TypeSelect);
            app.flowButton_9.Text = 'flow';
            app.flowButton_9.Value = true;
            app.pressureButton_9 = uiradiobutton(app.D4TypeSelect);
            app.pressureButton_9.Text = 'pressure';
            app.D5Tab = uitab(app.TabGroupDigital);
            app.D5Tab.Title = 'D5';
            app.D5UnitsOutputLabel = uilabel(app.D5Tab);
            app.D5UnitsOutputLabel.Text = 'P/unit';
            app.D5EnableSensor = uicheckbox(app.D5Tab);
            app.D5EnableSensor.ValueChangedFcn = createCallbackFcn(app, @D5EnableSensorValueChanged, true);
            app.D5EnableSensor.Text = 'Enable';
            app.OutputLabel_15 = uilabel(app.D5Tab);
            app.OutputLabel_15.Enable = 'off';
            app.OutputLabel_15.Text = 'Output:';
            app.D5EditFieldOutput = uieditfield(app.D5Tab, 'numeric');
            app.D5EditFieldOutput.Limits = [0 Inf];
            app.D5EditFieldOutput.Enable = 'off';
            app.D5TypeSelect = uibuttongroup(app.D5Tab);
            app.D5TypeSelect.SelectionChangedFcn = createCallbackFcn(app, @D5TypeSelectSelectionChanged, true);
            app.flowButton_10 = uiradiobutton(app.D5TypeSelect);
            app.flowButton_10.Text = 'flow';
            app.flowButton_10.Value = true;
            app.pressureButton_10 = uiradiobutton(app.D5TypeSelect);
            app.pressureButton_10.Text = 'pressure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.P1Tab = uitab(app.TabGroup);
            app.P1Tab.Title = 'P1';
            app.PumprateEditFieldLabel = uilabel(app.P1Tab);
            app.PumprateEditFieldLabel.Text = 'Pump rate:';
            app.P1RateEditField = uieditfield(app.P1Tab, 'numeric');
            app.P1RateUnitsLabel = uilabel(app.P1Tab);
            app.P1RateUnitsLabel.Text = 'L/min';
            app.FeedbackLabel_4 = uilabel(app.P1Tab);
            app.FeedbackLabel_4.Text = 'Feedback:';
            app.P1FeedbackSensor = uidropdown(app.P1Tab);
            app.P1FeedbackSensor.Items = {'Please select'};
            app.P1FeedbackSensor.Value = 'Please select';
            app.P2Tab = uitab(app.TabGroup);
            app.P2Tab.Title = 'P2';
            app.P2RateUnitsLabel = uilabel(app.P2Tab);
            app.P2RateUnitsLabel.Text = 'L/min';
            app.PumprateEditFieldLabel_2 = uilabel(app.P2Tab);
            app.PumprateEditFieldLabel_2.Text = 'Pump rate:';
            app.P2RateEditField = uieditfield(app.P2Tab, 'numeric');
            app.FeedbackLabel_3 = uilabel(app.P2Tab);
            app.FeedbackLabel_3.Text = 'Feedback:';
            app.P2FeedbackSensor = uidropdown(app.P2Tab);
            app.P2FeedbackSensor.Items = {'Please select'};
            app.P2FeedbackSensor.Value = 'Please select';
            app.P3Tab = uitab(app.TabGroup);
            app.P3Tab.Title = 'P3';
            app.P3RateUnitsLabel = uilabel(app.P3Tab);
            app.P3RateUnitsLabel.Text = 'L/min';
            app.PumprateEditFieldLabel_3 = uilabel(app.P3Tab);
            app.PumprateEditFieldLabel_3.Text = 'Pump rate:';
            app.P3RateEditField = uieditfield(app.P3Tab, 'numeric');
            app.FeedbackLabel_2 = uilabel(app.P3Tab);
            app.FeedbackLabel_2.Text = 'Feedback:';
            app.P3FeedbackSensor = uidropdown(app.P3Tab);
            app.P3FeedbackSensor.Items = {'Please select'};
            app.P3FeedbackSensor.Value = 'Please select';
            app.P4Tab = uitab(app.TabGroup);
            app.P4Tab.Title = 'P4';
            app.P4RateUnitsLabel = uilabel(app.P4Tab);
            app.P4RateUnitsLabel.Text = 'L/min';
            app.PumprateEditFieldLabel_4 = uilabel(app.P4Tab);
            app.PumprateEditFieldLabel_4.Text = 'Pump rate:';
            app.P4RateEditField = uieditfield(app.P4Tab, 'numeric');
            app.FeedbackLabel = uilabel(app.P4Tab);
            app.FeedbackLabel.Text = 'Feedback:';
            app.P4FeedbackSensor = uidropdown(app.P4Tab);
            app.P4FeedbackSensor.Items = {'Please select'};
            app.P4FeedbackSensor.Value = 'Please select';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end