classdef SensorCalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        schematic                    matlab.ui.control.Button
        FilepathEditFieldLabel       matlab.ui.control.Label
        FilepathEditField            matlab.ui.control.EditField
        FilenameEditFieldLabel       matlab.ui.control.Label
        FilenameEditField            matlab.ui.control.EditField
        FindfileButton               matlab.ui.control.Button
        H0EditFieldLabel             matlab.ui.control.Label
        FlowH0EditField              matlab.ui.control.NumericEditField
        D1EditFieldLabel             matlab.ui.control.Label
        FlowD1EditField              matlab.ui.control.NumericEditField
        D2EditFieldLabel             matlab.ui.control.Label
        FlowD2EditField              matlab.ui.control.NumericEditField
        FlowCalibrationCheckBox      matlab.ui.control.CheckBox
        PressureCalibrationCheckBox  matlab.ui.control.CheckBox
        H2EditFieldLabel             matlab.ui.control.Label
        PressureH2EditField          matlab.ui.control.NumericEditField
        H1EditFieldLabel             matlab.ui.control.Label
        PressureH1EditField          matlab.ui.control.NumericEditField
        H0EditField_2Label           matlab.ui.control.Label
        PressureH0EditField          matlab.ui.control.NumericEditField
        OpenGUIButton                matlab.ui.control.Button
        AdddataButton                matlab.ui.control.Button
        RemovepreviousButton         matlab.ui.control.Button
        ResetallButton               matlab.ui.control.Button
        NumberOfLinesLabel           matlab.ui.control.Label
        NumberOfLinesIndicator       matlab.ui.control.Label
        SensorEditFieldLabel_3       matlab.ui.control.Label
        PressureSensorEditField      matlab.ui.control.EditField
        mmLabel                      matlab.ui.control.Label
        mmLabel_2                    matlab.ui.control.Label
        mmLabel_3                    matlab.ui.control.Label
        mmLabel_5                    matlab.ui.control.Label
        mmLabel_6                    matlab.ui.control.Label
        mmLabel_7                    matlab.ui.control.Label
        InputDataPlot                matlab.ui.control.UIAxes
        TheoreticalPlot              matlab.ui.control.UIAxes
        FitPlot                      matlab.ui.control.UIAxes
        CalibrateButton              matlab.ui.control.Button
        SensorDropDownLabel          matlab.ui.control.Label
        FlowSensorEditField          matlab.ui.control.DropDown
    end


    properties (Access = public)
        CsvFilePath = ''; % The path and file name of the file selected by the user.
        Lines = [];
        NumberOfLines = 0;
    end

    methods (Access = private)
    
        function disableFlowSettings(app)
            app.FlowH0EditField.Enable = 'off';
            app.FlowD1EditField.Enable = 'off';
            app.FlowD2EditField.Enable = 'off';
            app.FlowSensorEditField.Enable = 'off';
        end
        
        function disablePressureSettings(app)
            app.PressureH0EditField.Enable = 'off';
            app.PressureH1EditField.Enable = 'off';
            app.PressureH2EditField.Enable = 'off';
            app.PressureSensorEditField.Enable = 'off';
        end
        
        function enableFlowSettings(app)
            app.FlowH0EditField.Enable = 'on';
            app.FlowD1EditField.Enable = 'on';
            app.FlowD2EditField.Enable = 'on';
            app.FlowSensorEditField.Enable = 'on';
        end
        
        function enablePressureSettings(app)
            app.PressureH0EditField.Enable = 'on';
            app.PressureH1EditField.Enable = 'on';
            app.PressureH2EditField.Enable = 'on';
            app.PressureSensorEditField.Enable = 'on';
        end
        
        function colIndex = colIndexSensor(app)
            colIndex = 0;
            if app.FlowCalibrationCheckBox.Value
                sensor = app.FlowSensorEditField.Value;
            else
                sensor = app.PressureSensorEditField.Value;
            end
            
            switch(sensor)
                case 'A1'
                    colIndex = 3;
                case 'A2'
                    colIndex = 4;
                case 'A3'
                    colIndex = 5;
                case 'A4'
                    colIndex = 6;
                case 'A5'
                    colIndex = 7;
                case 'D1'
                    colIndex = 8;
                case 'D2'
                    colIndex = 9;
                case 'D3'
                    colIndex = 10;
                case 'D4'
                    colIndex = 11;
                case 'D5'
                    colIndex = 12;
                otherwise
                    
            end
        end
        
        function incrementAndUpdateNumberOfLines(app)
            app.NumberOfLines = app.NumberOfLines + 1;
            app.NumberOfLinesIndicator.Text = int2str(app.NumberOfLines);
        end
        
        function decrementAndUpdateNumberOfLines(app)
            app.NumberOfLines = app.NumberOfLines - 1;
            app.NumberOfLinesIndicator.Text = int2str(app.NumberOfLines);
        end
        
        function plotInputData(app)
            cla(app.InputDataPlot);
            hold(app.InputDataPlot, 'on');
                      
            if app.NumberOfLines > 0
                for i = 1:app.NumberOfLines
                   plot(app.InputDataPlot, app.Lines{i}(:,1),app.Lines{i}(:,2));
                end
            end
            hold(app.InputDataPlot, 'off');
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            assignin('base', 'app', app);
        end

        % Button pushed function: FindfileButton
        function FindfileButtonPushed(app, event)
            [file, path] = uigetfile(fullfile(pwd,'Output','*.csv'),'Select sensor readout');
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            if ischar(file) && ischar(path)
                app.FilepathEditField.Value = path;
                app.FilenameEditField.Value = file;
                app.CsvFilePath = strcat(path, file);
            else
                warning('No file selected.');
                app.FilepathEditField.Value = '';
                app.FilenameEditField.Value = '';
                app.CsvFilePath = '';
            end
        end

        % Button pushed function: OpenGUIButton
        function OpenGUIButtonPushed(app, event)
            GUI;
        end

        % Value changed function: FlowCalibrationCheckBox
        function FlowCalibrationCheckBoxValueChanged(app, event)
            value = app.FlowCalibrationCheckBox.Value;
            if value
                app.PressureCalibrationCheckBox.Value = 0;
                disablePressureSettings(app);
                enableFlowSettings(app);
            else
                app.PressureCalibrationCheckBox.Value = 1;
                enablePressureSettings(app);
                disableFlowSettings(app);
            end
        end

        % Value changed function: PressureCalibrationCheckBox
        function PressureCalibrationCheckBoxValueChanged(app, event)
            value = app.PressureCalibrationCheckBox.Value;
            if value
                app.FlowCalibrationCheckBox.Value = 0;
                enablePressureSettings(app);
                disableFlowSettings(app);
            else
                app.FlowCalibrationCheckBox.Value = 1;
                disablePressureSettings(app);
                enableFlowSettings(app);
            end
        end

        % Button pushed function: AdddataButton
        function AdddataButtonPushed(app, event)
            if isempty(app.CsvFilePath)
                warning('No file selected.');
            else
                if colIndexSensor(app)>0
                    success = true;
                    try
                        csv = csvread(app.CsvFilePath, 2,0);
                    catch
                        warning('File does not have headers in first row.');
                        success = false;
                    end
                    
                    if success
                        incrementAndUpdateNumberOfLines(app); 
                        app.Lines{app.NumberOfLines}(:,1) = (csv(1:end,1)-csv(1,1))*24*60*60;
                        app.Lines{app.NumberOfLines}(:,2) = csv(1:end,colIndexSensor(app));
                        plotInputData(app);
                    end
                else
                    warning('No proper sensor chosen.');
                end
            end
        end

        % Callback function
        function FlowSensorEditFieldValueChanged(app, event)
            value = app.FlowSensorEditField.Value;
            switch(value)
                case {'A1', 'A2', 'A3', 'A4', 'A5', 'D1', 'D2', 'D3', 'D4', 'D5'}
                otherwise
                    app.FlowSensorEditField.Value = '';
            end
        end

        % Value changed function: PressureSensorEditField
        function PressureSensorEditFieldValueChanged(app, event)
            value = app.PressureSensorEditField.Value;
            switch(value)
                case {'A1', 'A2', 'A3', 'A4', 'A5', 'D1', 'D2', 'D3', 'D4', 'D5'}
                otherwise
                    app.PressureSensorEditField.Value = '';
            end
        end

        % Button pushed function: RemovepreviousButton
        function RemovepreviousButtonPushed(app, event)
            if app.NumberOfLines > 0
                app.Lines(app.NumberOfLines) = []; % using () and [] to properly remove entry.
                decrementAndUpdateNumberOfLines(app);
                plotInputData(app)
            end
        end

        % Button pushed function: ResetallButton
        function ResetallButtonPushed(app, event)
            app.Lines(:) = [];
            app.NumberOfLines = 0;
            app.NumberOfLinesIndicator.Text = int2str(app.NumberOfLines);
            plotInputData(app);
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            for i=1:app.NumberOfLines
                slopes = diff(app.Lines{i}(:,2));
                [~, index] = max(slopes);
                start(i) = index+1 % Increment the found index since the derivative removes first element.
                app.Lines{i}(1:index,:) = [];
                plotInputData(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.schematic = uibutton(app.UIFigure, 'push');
            app.schematic.Icon = 'calibrationSetup.png';
            app.schematic.Text = '';
            app.FilepathEditFieldLabel = uilabel(app.UIFigure);
            app.FilepathEditFieldLabel.Text = 'File path:';
            app.FilepathEditField = uieditfield(app.UIFigure, 'text');
            app.FilepathEditField.Editable = 'off';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name:';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Editable = 'off';
            app.FindfileButton = uibutton(app.UIFigure, 'push');
            app.FindfileButton.ButtonPushedFcn = createCallbackFcn(app, @FindfileButtonPushed, true);
            app.FindfileButton.Text = 'Find file';
            app.H0EditFieldLabel = uilabel(app.UIFigure);
            app.H0EditFieldLabel.Text = 'H0';
            app.FlowH0EditField = uieditfield(app.UIFigure, 'numeric');
            app.D1EditFieldLabel = uilabel(app.UIFigure);
            app.D1EditFieldLabel.Text = 'D1';
            app.FlowD1EditField = uieditfield(app.UIFigure, 'numeric');
            app.D2EditFieldLabel = uilabel(app.UIFigure);
            app.D2EditFieldLabel.Text = 'D2';
            app.FlowD2EditField = uieditfield(app.UIFigure, 'numeric');
            app.FlowCalibrationCheckBox = uicheckbox(app.UIFigure);
            app.FlowCalibrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlowCalibrationCheckBoxValueChanged, true);
            app.FlowCalibrationCheckBox.Text = 'Flow calibration';
            app.FlowCalibrationCheckBox.Value = true;
            app.PressureCalibrationCheckBox = uicheckbox(app.UIFigure);
            app.PressureCalibrationCheckBox.ValueChangedFcn = createCallbackFcn(app, @PressureCalibrationCheckBoxValueChanged, true);
            app.PressureCalibrationCheckBox.Text = 'Pressure calibration';
            app.H2EditFieldLabel = uilabel(app.UIFigure);
            app.H2EditFieldLabel.Enable = 'off';
            app.H2EditFieldLabel.Text = 'H2';
            app.PressureH2EditField = uieditfield(app.UIFigure, 'numeric');
            app.PressureH2EditField.Enable = 'off';
            app.H1EditFieldLabel = uilabel(app.UIFigure);
            app.H1EditFieldLabel.Enable = 'off';
            app.H1EditFieldLabel.Text = 'H1';
            app.PressureH1EditField = uieditfield(app.UIFigure, 'numeric');
            app.PressureH1EditField.Enable = 'off';
            app.H0EditField_2Label = uilabel(app.UIFigure);
            app.H0EditField_2Label.Enable = 'off';
            app.H0EditField_2Label.Text = 'H0';
            app.PressureH0EditField = uieditfield(app.UIFigure, 'numeric');
            app.PressureH0EditField.Enable = 'off';
            app.OpenGUIButton = uibutton(app.UIFigure, 'push');
            app.OpenGUIButton.ButtonPushedFcn = createCallbackFcn(app, @OpenGUIButtonPushed, true);
            app.OpenGUIButton.Text = 'Open GUI';
            app.AdddataButton = uibutton(app.UIFigure, 'push');
            app.AdddataButton.ButtonPushedFcn = createCallbackFcn(app, @AdddataButtonPushed, true);
            app.AdddataButton.Text = 'Add data';
            app.RemovepreviousButton = uibutton(app.UIFigure, 'push');
            app.RemovepreviousButton.ButtonPushedFcn = createCallbackFcn(app, @RemovepreviousButtonPushed, true);
            app.RemovepreviousButton.Text = 'Remove previous';
            app.ResetallButton = uibutton(app.UIFigure, 'push');
            app.ResetallButton.ButtonPushedFcn = createCallbackFcn(app, @ResetallButtonPushed, true);
            app.ResetallButton.Text = 'Reset all';
            app.NumberOfLinesLabel = uilabel(app.UIFigure);
            app.NumberOfLinesLabel.Text = 'Current size:';
            app.NumberOfLinesIndicator = uilabel(app.UIFigure);
            app.NumberOfLinesIndicator.Text = '0';
            app.SensorEditFieldLabel_3 = uilabel(app.UIFigure);
            app.SensorEditFieldLabel_3.Enable = 'off';
            app.SensorEditFieldLabel_3.Text = 'Sensor #';
            app.PressureSensorEditField = uieditfield(app.UIFigure, 'text');
            app.PressureSensorEditField.ValueChangedFcn = createCallbackFcn(app, @PressureSensorEditFieldValueChanged, true);
            app.PressureSensorEditField.Enable = 'off';
            app.mmLabel = uilabel(app.UIFigure);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.UIFigure);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_3 = uilabel(app.UIFigure);
            app.mmLabel_3.Text = 'mm';
            app.mmLabel_5 = uilabel(app.UIFigure);
            app.mmLabel_5.Text = 'mm';
            app.mmLabel_6 = uilabel(app.UIFigure);
            app.mmLabel_6.Text = 'mm';
            app.mmLabel_7 = uilabel(app.UIFigure);
            app.mmLabel_7.Text = 'mm';
            app.InputDataPlot = uiaxes(app.UIFigure);
            title(app.InputDataPlot, 'Input data')
            xlabel(app.InputDataPlot, 't [s]')
            ylabel(app.InputDataPlot, 'Sensor value')
            app.TheoreticalPlot = uiaxes(app.UIFigure);
            title(app.TheoreticalPlot, 'Theoretical curves')
            xlabel(app.TheoreticalPlot, 't')
            ylabel(app.TheoreticalPlot, 'Sensor value')
            app.FitPlot = uiaxes(app.UIFigure);
            title(app.FitPlot, 'Fit')
            xlabel(app.FitPlot, 't')
            ylabel(app.FitPlot, 'Sensor value')
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Text = 'CALIBRATE';
            app.SensorDropDownLabel = uilabel(app.UIFigure);
            app.SensorDropDownLabel.Text = 'Sensor #:';
            app.FlowSensorEditField = uidropdown(app.UIFigure);
            app.FlowSensorEditField.Items = {'A1', 'A2', 'A3', 'A4', 'A5', 'D1', 'D2', 'D3', 'D4', 'D5'};
            app.FlowSensorEditField.Value = 'A1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SensorCalibration

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end